classdef ESCWaveformGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ESCWaveGen                   matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        TabLoad                      matlab.ui.container.Tab
        SelectDataFolderRadar        matlab.ui.control.Button
        LabelListBox                 matlab.ui.control.Label
        SelectRadarFile              matlab.ui.control.ListBox
        SelectDataFolderLTE          matlab.ui.control.Button
        LTEDirLbl                    matlab.ui.control.Label
        RadarDirLbl                  matlab.ui.control.Label
        LTELMessageLbl               matlab.ui.control.Label
        RadarMetaFileLbl             matlab.ui.control.Label
        SelectRadarMetaDataFile      matlab.ui.control.Button
        LTEWaveformDurationSecLabel  matlab.ui.control.Label
        LTEWaveformDuration          matlab.ui.control.NumericEditField
        TabSettings                  matlab.ui.container.Tab
        SignalTiming                 matlab.ui.container.Panel
        LabelNumericEditField        matlab.ui.control.Label
        StartTime                    matlab.ui.control.NumericEditField
        Label28                      matlab.ui.control.Label
        SegmentTime                  matlab.ui.control.NumericEditField
        Label27                      matlab.ui.control.Label
        TotalTime                    matlab.ui.control.NumericEditField
        PanelLTE2                    matlab.ui.container.Panel
        LED_LTE2                     matlab.ui.control.Lamp
        Label12                      matlab.ui.control.Label
        KnobProbLTE2                 matlab.ui.control.Knob
        SliderFreqOffsetLTE2         matlab.ui.control.Slider
        Label13                      matlab.ui.control.Label
        Label14                      matlab.ui.control.Label
        KnobGainLTE2                 matlab.ui.control.Knob
        Label15                      matlab.ui.control.Label
        SwitchLTE2                   matlab.ui.control.ToggleSwitch
        LTE2FreqNum                  matlab.ui.control.NumericEditField
        PanelLTE1                    matlab.ui.container.Panel
        LED_LTE1                     matlab.ui.control.Lamp
        Label16                      matlab.ui.control.Label
        KnobProbLTE1                 matlab.ui.control.Knob
        SliderFreqOffsetLTE1         matlab.ui.control.Slider
        Label17                      matlab.ui.control.Label
        Label18                      matlab.ui.control.Label
        KnobGainLTE1                 matlab.ui.control.Knob
        Label19                      matlab.ui.control.Label
        SwitchLTE1                   matlab.ui.control.ToggleSwitch
        LTE1FreqNum                  matlab.ui.control.NumericEditField
        PanelRadar                   matlab.ui.container.Panel
        RadarLED                     matlab.ui.control.Lamp
        Label20                      matlab.ui.control.Label
        KnobProbRadar                matlab.ui.control.Knob
        SliderFreqOffsetRadar        matlab.ui.control.Slider
        Label21                      matlab.ui.control.Label
        Label22                      matlab.ui.control.Label
        KnobGainRadar                matlab.ui.control.Knob
        SwitchRadar                  matlab.ui.control.ToggleSwitch
        Label23                      matlab.ui.control.Label
        RadarFreqNum                 matlab.ui.control.NumericEditField
        Waveforms                    matlab.ui.container.Panel
        Stop                         matlab.ui.control.Button
        Generate                     matlab.ui.control.Button
        Display                      matlab.ui.container.ButtonGroup
        None                         matlab.ui.control.RadioButton
        Frequency                    matlab.ui.control.RadioButton
        Time                         matlab.ui.control.RadioButton
        SaveWaveform                 matlab.ui.control.Button
        ReadyStateLbl                matlab.ui.control.Label
        LabelNumericEditField2       matlab.ui.control.Label
        SaveScaleFactor              matlab.ui.control.NumericEditField
        SIRLbl                       matlab.ui.control.Label
        ShowSIR                      matlab.ui.control.CheckBox
        Tab                          matlab.ui.container.Tab
        StatPlot                     matlab.ui.container.TabGroup
        RunningAverage               matlab.ui.container.Tab
        SelectRunningAverageFolder   matlab.ui.control.Button
        RunAveLbl                    matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        ClearPlot                    matlab.ui.control.Button
        Label31                      matlab.ui.control.Label
        SelectRunninAverageFile      matlab.ui.control.ListBox
        DebugLbl                     matlab.ui.control.Label
    end


    properties (Access = private)
        currDir; % Description
        txWaveform_resampled;
        hSpec;
        Fs;
        hSpecState=0;
        FsLTE=15.36e6;
        RadarInfo;
        fileNo=1;
        Radar_folder_name;
        StopSimState=0;
        DisplayState=0; % None=0;Frequency=1;Time=2;
        hTScope;
        WaveformSaveflag=0;
        SaveWaveformFileId;
        RAInfo;
        BandPower;
        %% Defaults
        Parameters;
        RadarWaveformsPaths
        folderBrowser=folderBrowserGUI% Description
        %SourceRoot
        appDir
        configDir
        utilDir
        dspDir
    end
    
    
    methods (Access = private)


        function logicR=OnOff2logicfunc(app,OnOff)
            switch OnOff
                case 'On'
                    logicR=true;
                case 'Off'
                    logicR=false;
            end
        end
        
        function OnOffR=logic2OnOfffunc(app,logicIn)
            switch logicIn
                case true
                    OnOffR='On';
                case false
                    OnOffR='Off';
            end
        end
        
        function cdIfNotDepComp(app,cdDir)
            if ~(ismcc || isdeployed)
                cd(cdDir);
            end
            
        end
        
        %        function outStruct=addFields2stuct(app,inStuct)
        
        %            for fn = fieldnames(inStuct)'
        %                outStruct.(fn{1}) = inStuct.(fn{1});
        %            end
        
        %        end


    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.currDir=pwd;
            %% Defaults
            if ~(ismcc || isdeployed)
                appPath = mfilename('fullpath');
                [progDir,~,~] = fileparts(appPath);
                cd(progDir);
            end
            %appPath = mfilename('fullpath');
            %[app.appDir,~,~] = fileparts(appPath);
            app.appDir='..\app';
            
            app.utilDir='..\util';
            %cd(app.appDir);
            %app.SourceRoot=cd(cd('..')); %starting from app dir
            ConfigFileName='ESCWGConfigurationEmptyDir.json';
           % ConfigFilePath=fullfile(app.SourceRoot,'config',ConfigFileName);
            app.configDir='..\config';
            %cdIfNotDepComp(app,app.configDir);
            ConfigFile=fullfile(app.configDir,ConfigFileName);
            ConfigText = fileread(ConfigFile);
            %cdIfNotDepComp(app,app.appDir);
            ParStruct=jsondecode(ConfigText);
            app.Parameters=ParStruct.Parameters;
            %assignin('base','Parameters',  app.Parameters);
            app.Fs=app.Parameters.Basic.Fs*1e6;
            app.LTEWaveformDuration.Value=app.Parameters.Basic.LTEWaveformDuration;
            app.LTEWaveformDuration.Limits=[0.0 app.Parameters.Basic.LTEWaveformMaxDuration];
            %% Initialize Freq comp
            app.RadarFreqNum.Value=app.Parameters.FrequencyOffsetMHz.Radar;
            app.SliderFreqOffsetRadar.Value=app.Parameters.FrequencyOffsetMHz.Radar;
            app.LTE1FreqNum.Value=app.Parameters.FrequencyOffsetMHz.LTE1;
            app.SliderFreqOffsetLTE1.Value=app.Parameters.FrequencyOffsetMHz.LTE1;
            app.LTE2FreqNum.Value=app.Parameters.FrequencyOffsetMHz.LTE2;
            app.SliderFreqOffsetLTE2.Value=app.Parameters.FrequencyOffsetMHz.LTE2;
            %% Initiate Switches and Status LEDs
            app.SwitchRadar.Items={'',''};
            app.SwitchRadar.ItemsData={'Off','On'};
            app.SwitchLTE1.Items={'',''};
            app.SwitchLTE1.ItemsData={'Off','On'};
            app.SwitchLTE2.Items={'',''};
            app.SwitchLTE2.ItemsData={'Off','On'};
            
            app.SwitchRadar.Value=logic2OnOfffunc(app,app.Parameters.SignalStatus.Radar);
            app.SwitchLTE1.Value=logic2OnOfffunc(app,app.Parameters.SignalStatus.LTE1);
            app.SwitchLTE2.Value=logic2OnOfffunc(app,app.Parameters.SignalStatus.LTE2);
            app.RadarLED.Enable = logic2OnOfffunc(app,app.Parameters.SignalStatus.Radar);
            app.LED_LTE1.Enable = logic2OnOfffunc(app,app.Parameters.SignalStatus.LTE1);
            app.LED_LTE2.Enable = logic2OnOfffunc(app,app.Parameters.SignalStatus.LTE2);
            %% Initiate Spectrum Analyzer
            
            app.hSpec = dsp.SpectrumAnalyzer;
            %assignin('base','hSpec',  app.hSpec);
            
            fldnms=fieldnames(app.Parameters.SpectrumAnalyzer);
            for fldnm_Ind=1:length(fldnms)
                if ~isrow(app.Parameters.SpectrumAnalyzer.(fldnms{fldnm_Ind}))&& isnumeric(app.Parameters.SpectrumAnalyzer.(fldnms{fldnm_Ind}))
                    app.hSpec.(fldnms{fldnm_Ind}) = app.Parameters.SpectrumAnalyzer.(fldnms{fldnm_Ind}).';
                else
                    app.hSpec.(fldnms{fldnm_Ind}) = app.Parameters.SpectrumAnalyzer.(fldnms{fldnm_Ind});
                end
            end
            app.hSpec.SampleRate =app.Fs;
                    %{
            app.hSpec.PlotAsTwoSidedSpectrum = true;
            app.hSpec.RBWSource = 'Auto';
            %app.hSpec.RBWSource = 'Property';
            app.hSpec.FrequencyResolutionMethod='WindowLength';
            app.hSpec.FFTLengthSource='Property';
            app.hSpec.FFTLength=1024;%*8;
            app.hSpec.WindowLength=1024;%*8;
            app.hSpec.PowerUnits = 'dBW';
            app.hSpec.Position = [749 227 976 721];
            app.hSpec.YLimits=[-150 -50];
            app.hSpec.PlotMaxHoldTrace=true;
            
            app.hSpec.SpectralAverages=32;
            app.hSpec.OverlapPercent=1;
            app.hSpec.Window='Hann'; % default 'Hann' , others 'Kaiser', ---
                    %}
                    %assignin('base','hSpec',  app.hSpec);
                    %%
                    %%
            app.hTScope= dsp.TimeScope;       
            fldnms=fieldnames(app.Parameters.TimeScope);
            for fldnm_Ind=1:length(fldnms)
                if ~isrow(app.Parameters.TimeScope.(fldnms{fldnm_Ind}))&& isnumeric(app.Parameters.TimeScope.(fldnms{fldnm_Ind}))
                    app.hTScope.(fldnms{fldnm_Ind}) = app.Parameters.TimeScope.(fldnms{fldnm_Ind}).';
                else
                    app.hTScope.(fldnms{fldnm_Ind}) = app.Parameters.TimeScope.(fldnms{fldnm_Ind});
                end
            end
                    
            app.hTScope.SampleRate=app.Fs;
                    %{
            app.hTScope.ShowGrid=true;
            app.hTScope.YLimits=[0 10e-4];
                    %}
                    %%
                    %assignin('base','hTScope',  app.hTScope);
                    app.LTELMessageLbl.Text=sprintf('No LTE Data loaded');
                    
                    %% Read Measurement Parameters
                    MetaRadar_info_full_path=fullfile(app.Parameters.Basic.DefaultDirectories.RadarMeasInfoFile_path,...
                        app.Parameters.Basic.DefaultDirectories.RadarMeasInfoFile_name);
                    if (exist(MetaRadar_info_full_path, 'file') == 2)
                        app.SelectRadarMetaDataFile.Enable='off';
                        
                       % cd (app.SourceRoot)
                        %cd('util')
                        cdIfNotDepComp(app,app.utilDir);
                        app.RadarInfo=read_xsl_info_f(MetaRadar_info_full_path);
                        cdIfNotDepComp(app,app.appDir);
                        app.RadarMetaFileLbl.Text=MetaRadar_info_full_path;
                        
                    end
                    %% Debug
                    %app.DebugLbl.Text=[num2str(double(isempty(app.txWaveform_resampled)))];
        end

        % Button pushed function: SelectDataFolderRadar
        function SelectDataFolderRadarButtonPushed(app, event)
                dialogText='Select Radar waveforms folder';
                defaultExt='dat';
                app.folderBrowser=setDefaultDir(app.folderBrowser,app.Parameters.Basic.DefaultDirectories.RadarDir);
                app.folderBrowser=setDialogText(app.folderBrowser,dialogText);
                app.folderBrowser=setDefaultExt(app.folderBrowser,defaultExt);
                app.folderBrowser=SelectFolder(app.folderBrowser,app.ESCWaveGen);
                fileNames=getFileNames(app.folderBrowser,'withext');
                if ~isempty(fileNames)
                    app.SelectRadarFile.Items=fileNames;
                    app.RadarWaveformsPaths=getFullFileNamesWithPath(app.folderBrowser,'withext');
                    app.SelectRadarFile.ItemsData=1:length(fileNames);
                    %assignin('base','file_paths',app.RadarWaveformsPaths)
                end
                %clear fileNames;
        end

        % Value changed function: SwitchRadar
        function SwitchRadarValueChanged(app, event)
                
                app.RadarLED.Enable = app.SwitchRadar.Value;
                
                %logicR=OnOff2logicfunc(app,app.SwitchRadar.Value);
                
                
        end

        % Button pushed function: SelectDataFolderLTE
        function SelectDataFolderLTEButtonPushed(app, event)
                dialogText='Select LTE waveforms folder';
                defaultExt='mat';
                app.folderBrowser=setDefaultDir(app.folderBrowser,app.Parameters.Basic.DefaultDirectories.LTEDir);
                app.folderBrowser=setDialogText(app.folderBrowser,dialogText);
                app.folderBrowser=setDefaultExt(app.folderBrowser,defaultExt);
                app.folderBrowser=SelectFolder(app.folderBrowser,app.ESCWaveGen);
                fileNames=getFileNames(app.folderBrowser,'withext');
                if ~isempty(fileNames)
                    LTEWaveformsPaths=getFullFileNamesWithPath(app.folderBrowser,'withext');
                    %assignin('base','file_paths',app.RadarWaveformsPaths)
                end
                clear fileNames;
                tic;
                [p,q] = rat(app.Fs / app.FsLTE);
                
                txwavelong=[];
                app.LTEDirLbl.Text=['LTE Data Dir:',app.Parameters.Basic.DefaultDirectories.LTEDir];
                app.LTELMessageLbl.Text=sprintf('Loading LTE data...\n this may take a few minutes');
                for f_in=1:length(LTEWaveformsPaths) % comment for speed use for f_in=1:2
                    %for f_in=1:2
                    WaveFile(f_in)=load(char(LTEWaveformsPaths(f_in)));
                    txwavelong=[txwavelong;WaveFile(f_in).txWaveform];
                    
                end
                app.txWaveform_resampled = resample(txwavelong,p,q);
                
                my_toc=toc;
                app.LTELMessageLbl.Text=sprintf('Done loading LTE after %d seconds',my_toc);
                
                
        end

        % Value changed function: SelectRadarFile
        function SelectRadarFileValueChanged(app, event)
                app.fileNo = app.SelectRadarFile.Value;
        end

        % Button pushed function: Generate
        function GenerateButtonPushed(app, event)
            tic
                if ~isempty(app.SelectRadarFile.Items)
                    file_path_name=char(app.RadarWaveformsPaths(app.fileNo));
                else
                    file_path_name=[];
                end
                
                if  ~(exist(file_path_name, 'file') == 2) || isempty(app.txWaveform_resampled)
                    uialert(app.ESCWaveGen,'Please Select a Folder for Radar Measurment files and/or LTE folder','File not found');
                    
                else
                    app.BandPower.Radar.Max=0;
                    app.BandPower.Radar.Min=1e10;
                    app.BandPower.Radar.Ave=0;
                    
                    app.BandPower.LTE.Max=0;
                    app.BandPower.LTE.Min=1e10;
                    app.BandPower.LTE.Ave=0;
                    
                    app.BandPower.SIR.Max=0;
                    app.BandPower.SIR.Min=1e10;
                    app.BandPower.SIR.Ave=0;
                    Ts=1/app.Fs;
                    %TPartSeg=10e-3;
                    NPartSeg=floor(app.SegmentTime.Value/Ts);
                    NTot=floor(app.TotalTime.Value/Ts);
                    NLoop=floor(NTot/NPartSeg);
                    NLTEavAilable=length(app.txWaveform_resampled);
                    NLTESegAvailble=floor(NLTEavAilable/NPartSeg);
                    
                    if app.DisplayState==2
                        app.hTScope.TimeSpan=NPartSeg/app.Fs;
                    end
                    t0=app.StartTime.Value;
                    I=0;
                    
                    %Gainfactor=1;
                    Rf_gain=app.RadarInfo.ADCscalefactorFADC(app.fileNo)/10.6;
                    %% Disable Display buttons while running
                    app.None.Enable='off';
                    app.Frequency.Enable='off';
                    app.Time.Enable='off';
                    %% Disable save button while running
                    app.SaveWaveform.Enable='off';
                    app.SaveScaleFactor.Enable='off';
                    %%
                    app.ReadyStateLbl.Text='Running...';
                    app.ReadyStateLbl.FontColor=[0.74,0.09,0.20];
                    %% Data save scaling
                    %New_Max=2^15-1;
                    %New_Min=-2^15;
                    %Old_Max=1-1/2^15;
                    %Old_Min=-1;
                    %Old_Range = (Old_Max - Old_Min);
                    %New_Range = (New_Max - New_Min);
                    
                    %%
                    %cd (app.SourceRoot)
                    %cd('util')
                    cdIfNotDepComp(app,app.utilDir);
                    while (~(app.StopSimState) && (I<NLoop))
                        I=I+1;
                        seekPosition=4*round(t0/Ts);
                        windowLength=NPartSeg;
                        t0=t0+NPartSeg*Ts;
                        t=t0+((0:(NPartSeg-1)).')*Ts;
                        [res, Data_Vector_c] = radar_data_reader(file_path_name,seekPosition,windowLength);
                        %Data_Vector_c=0;
                        nSelectLTEseg=randi(NLTESegAvailble-1,2,1);
            
                        RadarLTEswitches=[OnOff2logicfunc(app,app.SwitchRadar.Value), OnOff2logicfunc(app,app.SwitchLTE1.Value) ,OnOff2logicfunc(app,app.SwitchLTE2.Value)];
                        RadarLTEstatus=(binornd([1 1 1],[app.KnobProbRadar.Value app.KnobProbLTE1.Value app.KnobProbLTE2.Value])).*RadarLTEswitches;
            
                        LTEch1shift=exp(1i*(2*pi*app.Parameters.FrequencyOffsetMHz.LTE1*1e6)*t);
                        LTEch2shift=exp(1i*(2*pi*app.Parameters.FrequencyOffsetMHz.LTE2*1e6)*t);
                        Radarshift=exp(1i*(2*pi*app.Parameters.FrequencyOffsetMHz.Radar*1e6)*t);
                        
                        LTEsig1=RadarLTEstatus(2)*(LTEch1shift.*app.txWaveform_resampled(nSelectLTEseg(1)*NPartSeg:((nSelectLTEseg(1)+1)*NPartSeg-1)));
                        LTEsig2=RadarLTEstatus(3)*(LTEch2shift.*app.txWaveform_resampled(nSelectLTEseg(2)*NPartSeg:((nSelectLTEseg(2)+1)*NPartSeg-1)));
                        LTEsig=app.KnobGainLTE1.Value*LTEsig1+app.KnobGainLTE2.Value*LTEsig2;
                        
                        Radarsig=app.KnobGainRadar.Value*Rf_gain*RadarLTEstatus(1)*(Radarshift.*Data_Vector_c);
                        
                        MixedWaveform=Radarsig+LTEsig;
                        %% Power Calculations
                        %{
                        if (RadarLTEstatus(1) && (RadarLTEstatus(2)|| RadarLTEstatus(3)))
                            app.BandPower.freqrange=[app.Parameters.FrequencyOffsetMHz.Radar*1e6-0.5e6, app.Parameters.FrequencyOffsetMHz.Radar*1e6+0.5e6];
                            app.BandPower.Radar.power=bandpower(Radarsig,app.Fs,app.BandPower.freqrange);
                            app.BandPower.LTE.power=bandpower(LTEsig,app.Fs,app.BandPower.freqrange);
                            app.BandPower.SIR.insta=app.BandPower.Radar.power/app.BandPower.LTE.power;
                            app.SIRLbl.Text=['SIR=',num2str(10*log10(app.BandPower.SIR.insta)),' dBw'];
                            app.BandPower.SIR.Max=max(app.BandPower.SIR.Max,app.BandPower.SIR.insta);
                            app.BandPower.SIR.Min=min(app.BandPower.SIR.Max,app.BandPower.SIR.insta);
                            app.BandPower.SIR.Ave= app.BandPower.SIR.Ave+app.BandPower.SIR.insta;
                        end
                        %}
                        
                        if app.WaveformSaveflag==1
                           
                            data_out(:,1)=imag(MixedWaveform*app.SaveScaleFactor.Value); % simple scaling
                            data_out(:,2)=real(MixedWaveform*app.SaveScaleFactor.Value); %simple scaling
                            
                            data_out_interleaved=reshape(data_out.',[],1);
                            
                            fwrite(app.SaveWaveformFileId,data_out_interleaved,'int16');
                            %app.DebugLbl.Text=['Reached save if, save flag=',num2str(app.WaveformSaveflag)];
                        end
                        
                        if app.DisplayState==1
                            step(app.hSpec,MixedWaveform);
                        end
                        
                        if app.DisplayState==2
                            step(app.hTScope,abs(MixedWaveform));
                        end
                    end
                    %%
                    %{
                    if (RadarLTEstatus(1) && (RadarLTEstatus(2)|| RadarLTEstatus(3)))
                        app.BandPower.SIR.Ave=app.BandPower.SIR.Ave/I;
                        app.SIRLbl.Text=sprintf('SIR Ave=%.6g dBw \nSIR Max=%4f dBw \nSIR Min=%4f dBw \n',(10*log10(app.BandPower.SIR.Ave)),(10*log10(app.BandPower.SIR.Max)),(10*log10(app.BandPower.SIR.Min)));
                        %app.SIRLbl.Text=['SIR Ave=',num2str(10*log10(app.BandPower.SIR.Ave)),' dBw'];
                    end
                   %} 
                end


                %% Go back to initial state
                if app.WaveformSaveflag==1
                    app.WaveformSaveflag=0;
                    fclose(app.SaveWaveformFileId);
                end
                app.StopSimState=0;
                app.ReadyStateLbl.Text='Ready...';
                app.ReadyStateLbl.FontColor=[0.07,0.53,0.07];
                %% Enabel Display buttons when done
                app.None.Enable='on';
                app.Frequency.Enable='on';
                app.Time.Enable='on';
                %% Enabel save button after running
                app.SaveWaveform.Enable='on';
                app.SaveScaleFactor.Enable='on';
                cdIfNotDepComp(app,app.appDir)
                
                genTime=toc;
                assignin('base','genTime',genTime);
        end

        % Selection changed function: Display
        function DisplaySelectionChanged(app, event)
                DisplaySelect= app.Display.SelectedObject;
                switch DisplaySelect.Text
                    case 'None'
                        app.DisplayState=0;
                        app.hTScope.hide;
                        app.hSpec.hide;
                    case 'Frequency'
                        app.DisplayState=1;
                        app.hTScope.hide;
                        app.hSpec.show;
                    case 'Time'
                        app.hSpec.hide;
                        app.hTScope.show;
                        app.DisplayState=2;
                end
                %app.DebugLbl.Text=num2str(app.DisplayState);
        end

        % Button pushed function: Stop
        function StopButtonPushed(app, event)
                app.StopSimState=1;
                app.ReadyStateLbl.Text='Ready...';
                app.ReadyStateLbl.FontColor=[0.07,0.53,0.07];
                %% Enabel Display buttons while not running
                app.None.Enable='on';
                app.Frequency.Enable='on';
                app.Time.Enable='on';
        end

        % Button pushed function: SaveWaveform
        function SaveWaveformButtonPushed(app, event)
                [WaveformSavefile, WaveformSavepath] = uiputfile(...
                    {'*.dat';'*.*'},...
                    'Save waveform as','D:\Spectrum-Share');
                if (sum(WaveformSavefile~=0) && sum(WaveformSavepath~=0))
                    app.WaveformSaveflag=1;
                    file_path_name=fullfile(WaveformSavepath, WaveformSavefile);
                    %app.DebugLbl.Text=file_path_name;
                    [app.SaveWaveformFileId,errmsg_write]=fopen(file_path_name,'a','l','UTF-8');
                    %app.DebugLbl.Text=num2str(app.SaveWaveformFileId);
                end
        end

        % Button pushed function: SelectRunningAverageFolder
        function SelectRunningAverageFolderButtonPushed(app, event)
                dialogText='Select Radar Running Average Folder';
                defaultExt='mat';
                app.folderBrowser=setDefaultDir(app.folderBrowser,'');
                app.folderBrowser=setDialogText(app.folderBrowser,dialogText);
                app.folderBrowser=setDefaultExt(app.folderBrowser,defaultExt);
                app.folderBrowser=SelectFolder(app.folderBrowser,app.ESCWaveGen);
                fileNames=getFileNames(app.folderBrowser,'withext');
                RunAveInd=strcmp(fileNames,{'RunAvrgInfo.mat'});
                if ~isempty(fileNames) && sum(RunAveInd)==1
                    app.SelectRunninAverageFile.Items=fileNames(~RunAveInd);
                    fileNamesPaths=getFullFileNamesWithPath(app.folderBrowser,'withext');
                    app.SelectRunninAverageFile.ItemsData=fileNamesPaths(~RunAveInd);
                    load(char(fileNamesPaths(RunAveInd)));
                    app.RAInfo=RunAvrgInfo(1);
            
                end
        end

        % Button pushed function: ClearPlot
        function ClearPlotButtonPushed(app, event)
                %clf(app.UIAxes)
                plot(app.UIAxes,-60)
                set(app.UIAxes,'XTick',...
                    [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50 52 54 56 58 60 62 64 66 68 70]);
                hold(app.UIAxes,'off');
                %app.SelectFile.Value=[];
                %hold(app.UIAxes,'off');
        end

        % Value changed function: SelectRunninAverageFile
        function SelectRunninAverageFileValueChanged(app, event)
                %value = app.SelectRunninAverageFile.Value;
                
                %value = app.SelectFile.Value;
                
                %Clear Axes first
                plot(app.UIAxes,-60)
                set(app.UIAxes,'XTick',...
                    [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50 52 54 56 58 60 62 64 66 68 70]);
                hold(app.UIAxes,'off');
                
                %Rest of function
                load(app.SelectRunninAverageFile.Value)
                meanDeviationdB=1;
                hold(app.UIAxes,'on');
                cleanIndx=~isnan(Data_avg_mag);
                plot(app.UIAxes,app.RAInfo.xtime(cleanIndx),10*log10(10^(meanDeviationdB/10)*mean(Data_avg_mag(cleanIndx))*ones(size(app.RAInfo.xtime(cleanIndx)))))
                plot(app.UIAxes,app.RAInfo.xtime(cleanIndx),10*log10(Data_avg_mag(cleanIndx)))
                box(app.UIAxes,'on');
                grid(app.UIAxes,'on');
                app.UIAxes.Title.String='Running Average Plot';
                app.UIAxes.YLabel.String='Power dB';
                app.UIAxes.XLabel.String='time sec';
                %assignin('base','axes1',app.UIAxes)
                % Set the remaining axes properties
                set(app.UIAxes,'XTick',...
                    [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50 52 54 56 58 60 62 64 66 68 70]);
                
                hold(app.UIAxes,'off');
        end

        % Value changed function: SwitchLTE1
        function SwitchLTE1ValueChanged(app, event)
                app.LED_LTE1.Enable = app.SwitchLTE1.Value;
        end

        % Value changed function: SwitchLTE2
        function SwitchLTE2ValueChanged(app, event)
                app.LED_LTE2.Enable = app.SwitchLTE2.Value;
        end

        % Value changing function: SliderFreqOffsetRadar
        function SliderFreqOffsetRadarValueChanging(app, event)
                %changingValue = event.Value;
                app.Parameters.FrequencyOffsetMHz.Radar=event.Value;
                app.RadarFreqNum.Value= app.Parameters.FrequencyOffsetMHz.Radar;
                % app.DebugLbl.Text=num2str(changingValue);
        end

        % Value changed function: RadarFreqNum
        function RadarFreqNumValueChanged(app, event)
                app.Parameters.FrequencyOffsetMHz.Radar=app.RadarFreqNum.Value;
                app.SliderFreqOffsetRadar.Value =app.Parameters.FrequencyOffsetMHz.Radar;
                
        end

        % Value changing function: SliderFreqOffsetLTE1
        function SliderFreqOffsetLTE1ValueChanging(app, event)
                %changingValue = event.Value;
                app.Parameters.FrequencyOffsetMHz.LTE1=event.Value;
                app.LTE1FreqNum.Value=app.Parameters.FrequencyOffsetMHz.LTE1;
        end

        % Value changed function: LTE1FreqNum
        function LTE1FreqNumValueChanged(app, event)
                app.Parameters.FrequencyOffsetMHz.LTE1 = app.LTE1FreqNum.Value;
                app.SliderFreqOffsetLTE1.Value=app.Parameters.FrequencyOffsetMHz.LTE1;
        end

        % Value changing function: SliderFreqOffsetLTE2
        function SliderFreqOffsetLTE2ValueChanging(app, event)
                app.Parameters.FrequencyOffsetMHz.LTE2=event.Value;
                app.LTE2FreqNum.Value=app.Parameters.FrequencyOffsetMHz.LTE2;
        end

        % Value changed function: LTE2FreqNum
        function LTE2FreqNumValueChanged(app, event)
                app.Parameters.FrequencyOffsetMHz.LTE2= app.LTE2FreqNum.Value;
                app.SliderFreqOffsetLTE2.Value=app.Parameters.FrequencyOffsetMHz.LTE2;
        end

        % Button pushed function: SelectRadarMetaDataFile
        function SelectRadarMetaDataFileButtonPushed(app, event)
            [app.Parameters.Basic.DefaultDirectories.RadarMeasInfoFile_name,...
                app.Parameters.Basic.DefaultDirectories.RadarMeasInfoFile_path] = ...
                uigetfile({'*.xlsx'},'Radar Meta Data File Selector');
            MetaRadar_info_full_path=fullfile(app.Parameters.Basic.DefaultDirectories.RadarMeasInfoFile_path,...
                app.Parameters.Basic.DefaultDirectories.RadarMeasInfoFile_name);
            assignin('base','MetaRadar_info_full_path',MetaRadar_info_full_path);
            if (exist(MetaRadar_info_full_path, 'file') == 2)
                %cd (app.SourceRoot)
                %cd('util')
                cdIfNotDepComp(app,app.utilDir);
                app.RadarInfo=read_xsl_info_f(MetaRadar_info_full_path);
                cdIfNotDepComp(app,app.appDir)
                app.RadarMetaFileLbl.Text=MetaRadar_info_full_path;
                app.SelectRadarMetaDataFile.Enable= 'off';
            else
                uialert(app.ESCWaveGen,'No Meta data file Selected','Invalid File');
            end
            
        end

        % Value changed function: LTEWaveformDuration
        function LTEWaveformDurationValueChanged(app, event)
                app.Parameters.Basic.LTEWaveformDuration= app.LTEWaveformDuration.Value;
                
        end

        % Value changed function: ShowSIR
        function ShowSIRValueChanged(app, event)
            value = app.ShowSIR.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ESCWaveGen = uifigure;
            app.ESCWaveGen.Name = 'ESC Waveform Generator V 1.00';
            setAutoResize(app, app.ESCWaveGen, true)
            app.TabGroup = uitabgroup(app.ESCWaveGen);
            app.TabLoad = uitab(app.TabGroup);
            app.TabLoad.Title = 'Signal Sources';
            app.TabLoad.Units = 'pixels';
            app.SelectDataFolderRadar = uibutton(app.TabLoad, 'push');
            app.SelectDataFolderRadar.ButtonPushedFcn = createCallbackFcn(app, @SelectDataFolderRadarButtonPushed, true);
            app.SelectDataFolderRadar.Text = 'Select Radar Data Folder';
            app.LabelListBox = uilabel(app.TabLoad);
            app.LabelListBox.Text = 'Select File';
            app.SelectRadarFile = uilistbox(app.TabLoad);
            app.SelectRadarFile.Items = {};
            app.SelectRadarFile.ValueChangedFcn = createCallbackFcn(app, @SelectRadarFileValueChanged, true);
            app.SelectRadarFile.Value = {};
            app.SelectDataFolderLTE = uibutton(app.TabLoad, 'push');
            app.SelectDataFolderLTE.ButtonPushedFcn = createCallbackFcn(app, @SelectDataFolderLTEButtonPushed, true);
            app.SelectDataFolderLTE.Text = 'Select LTE Data Folder';
            app.LTEDirLbl = uilabel(app.TabLoad);
            app.LTEDirLbl.Text = 'LTE Data Dir:';
            app.RadarDirLbl = uilabel(app.TabLoad);
            app.RadarDirLbl.Text = 'Radar Data Dir:';
            app.LTELMessageLbl = uilabel(app.TabLoad);
            app.RadarMetaFileLbl = uilabel(app.TabLoad);
            app.RadarMetaFileLbl.Text = 'Radar Meta Data File: ';
            app.SelectRadarMetaDataFile = uibutton(app.TabLoad, 'push');
            app.SelectRadarMetaDataFile.ButtonPushedFcn = createCallbackFcn(app, @SelectRadarMetaDataFileButtonPushed, true);
            app.SelectRadarMetaDataFile.Text = 'Select Radar Meta Data File:';
            app.LTEWaveformDurationSecLabel = uilabel(app.TabLoad);
            app.LTEWaveformDurationSecLabel.Text = 'LTE Waveform Duration Sec';
            app.LTEWaveformDuration = uieditfield(app.TabLoad, 'numeric');
            app.LTEWaveformDuration.ValueChangedFcn = createCallbackFcn(app, @LTEWaveformDurationValueChanged, true);
            app.TabSettings = uitab(app.TabGroup);
            app.TabSettings.Title = 'Waveform Generation';
            app.TabSettings.Units = 'pixels';
            app.SignalTiming = uipanel(app.TabSettings);
            app.SignalTiming.Title = 'Signal Timing';
            app.LabelNumericEditField = uilabel(app.SignalTiming);
            app.LabelNumericEditField.Text = 'Start time';
            app.StartTime = uieditfield(app.SignalTiming, 'numeric');
            app.StartTime.Limits = [0 60];
            app.StartTime.Value = 1.36;
            app.Label28 = uilabel(app.SignalTiming);
            app.Label28.Text = 'Segment Time';
            app.SegmentTime = uieditfield(app.SignalTiming, 'numeric');
            app.SegmentTime.Limits = [0.001 1];
            app.SegmentTime.Value = 0.01;
            app.Label27 = uilabel(app.SignalTiming);
            app.Label27.Text = 'Total time';
            app.TotalTime = uieditfield(app.SignalTiming, 'numeric');
            app.TotalTime.Limits = [0.1 60];
            app.TotalTime.Value = 1;
            app.PanelLTE2 = uipanel(app.TabSettings);
            app.PanelLTE2.Title = 'LTE #2';
            app.LED_LTE2 = uilamp(app.PanelLTE2);
            app.LED_LTE2.Enable = 'off';
            app.Label12 = uilabel(app.PanelLTE2);
            app.Label12.Text = 'Probability';
            app.KnobProbLTE2 = uiknob(app.PanelLTE2, 'continuous');
            app.KnobProbLTE2.Limits = [0 1];
            app.KnobProbLTE2.Value = 1;
            app.SliderFreqOffsetLTE2 = uislider(app.PanelLTE2);
            app.SliderFreqOffsetLTE2.Limits = [-12 12];
            app.SliderFreqOffsetLTE2.ValueChangingFcn = createCallbackFcn(app, @SliderFreqOffsetLTE2ValueChanging, true);
            app.SliderFreqOffsetLTE2.MinorTicks = [];
            app.SliderFreqOffsetLTE2.Value = 5;
            app.Label13 = uilabel(app.PanelLTE2);
            app.Label13.Text = 'Frequency Offset MHz';
            app.Label14 = uilabel(app.PanelLTE2);
            app.Label14.Text = 'Gain';
            app.KnobGainLTE2 = uiknob(app.PanelLTE2, 'continuous');
            app.KnobGainLTE2.Limits = [0 0.01];
            app.KnobGainLTE2.Value = 0.0005;
            app.Label15 = uilabel(app.PanelLTE2);
            app.Label15.Text = '';
            app.SwitchLTE2 = uiswitch(app.PanelLTE2, 'toggle');
            app.SwitchLTE2.ValueChangedFcn = createCallbackFcn(app, @SwitchLTE2ValueChanged, true);
            app.LTE2FreqNum = uieditfield(app.PanelLTE2, 'numeric');
            app.LTE2FreqNum.ValueChangedFcn = createCallbackFcn(app, @LTE2FreqNumValueChanged, true);
            app.LTE2FreqNum.Limits = [-12 12];
            app.PanelLTE1 = uipanel(app.TabSettings);
            app.PanelLTE1.Title = 'LTE #1';
            app.LED_LTE1 = uilamp(app.PanelLTE1);
            app.LED_LTE1.Enable = 'off';
            app.Label16 = uilabel(app.PanelLTE1);
            app.Label16.Text = 'Probability';
            app.KnobProbLTE1 = uiknob(app.PanelLTE1, 'continuous');
            app.KnobProbLTE1.Limits = [0 1];
            app.KnobProbLTE1.Value = 1;
            app.SliderFreqOffsetLTE1 = uislider(app.PanelLTE1);
            app.SliderFreqOffsetLTE1.Limits = [-12 12];
            app.SliderFreqOffsetLTE1.MajorTickLabels = {'-12', '', '', '', '', '', '12'};
            app.SliderFreqOffsetLTE1.ValueChangingFcn = createCallbackFcn(app, @SliderFreqOffsetLTE1ValueChanging, true);
            app.SliderFreqOffsetLTE1.MinorTicks = [];
            app.SliderFreqOffsetLTE1.Value = -5;
            app.Label17 = uilabel(app.PanelLTE1);
            app.Label17.Text = 'Frequency Offset MHz';
            app.Label18 = uilabel(app.PanelLTE1);
            app.Label18.Text = 'Gain';
            app.KnobGainLTE1 = uiknob(app.PanelLTE1, 'continuous');
            app.KnobGainLTE1.Limits = [0 0.01];
            app.KnobGainLTE1.Value = 0.0005;
            app.Label19 = uilabel(app.PanelLTE1);
            app.Label19.Text = '';
            app.SwitchLTE1 = uiswitch(app.PanelLTE1, 'toggle');
            app.SwitchLTE1.ValueChangedFcn = createCallbackFcn(app, @SwitchLTE1ValueChanged, true);
            app.LTE1FreqNum = uieditfield(app.PanelLTE1, 'numeric');
            app.LTE1FreqNum.ValueChangedFcn = createCallbackFcn(app, @LTE1FreqNumValueChanged, true);
            app.LTE1FreqNum.Limits = [-12 12];
            app.PanelRadar = uipanel(app.TabSettings);
            app.PanelRadar.Title = 'Radar';
            app.RadarLED = uilamp(app.PanelRadar);
            app.RadarLED.Enable = 'off';
            app.Label20 = uilabel(app.PanelRadar);
            app.Label20.Text = 'Probability';
            app.KnobProbRadar = uiknob(app.PanelRadar, 'continuous');
            app.KnobProbRadar.Limits = [0 1];
            app.KnobProbRadar.Value = 1;
            app.SliderFreqOffsetRadar = uislider(app.PanelRadar);
            app.SliderFreqOffsetRadar.Limits = [-12 12];
            app.SliderFreqOffsetRadar.ValueChangingFcn = createCallbackFcn(app, @SliderFreqOffsetRadarValueChanging, true);
            app.SliderFreqOffsetRadar.MinorTicks = [];
            app.Label21 = uilabel(app.PanelRadar);
            app.Label21.Text = 'Frequency Offset MHz';
            app.Label22 = uilabel(app.PanelRadar);
            app.Label22.Text = 'Gain';
            app.KnobGainRadar = uiknob(app.PanelRadar, 'continuous');
            app.KnobGainRadar.Value = 1;
            app.SwitchRadar = uiswitch(app.PanelRadar, 'toggle');
            app.SwitchRadar.ValueChangedFcn = createCallbackFcn(app, @SwitchRadarValueChanged, true);
            app.Label23 = uilabel(app.PanelRadar);
            app.Label23.Text = '';
            app.RadarFreqNum = uieditfield(app.PanelRadar, 'numeric');
            app.RadarFreqNum.ValueChangedFcn = createCallbackFcn(app, @RadarFreqNumValueChanged, true);
            app.RadarFreqNum.Limits = [-12 12];
            app.Waveforms = uipanel(app.TabSettings);
            app.Waveforms.Title = 'Waveforms';
            app.Stop = uibutton(app.Waveforms, 'push');
            app.Stop.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.Stop.Text = 'Stop';
            app.Generate = uibutton(app.Waveforms, 'push');
            app.Generate.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.Generate.Text = 'Generate';
            app.Display = uibuttongroup(app.Waveforms);
            app.Display.SelectionChangedFcn = createCallbackFcn(app, @DisplaySelectionChanged, true);
            app.Display.Title = 'Signal Display';
            app.None = uiradiobutton(app.Display);
            app.None.Text = 'None';
            app.None.Value = true;
            app.Frequency = uiradiobutton(app.Display);
            app.Frequency.Text = 'Frequency';
            app.Time = uiradiobutton(app.Display);
            app.Time.Text = 'Time';
            app.SaveWaveform = uibutton(app.Waveforms, 'push');
            app.SaveWaveform.ButtonPushedFcn = createCallbackFcn(app, @SaveWaveformButtonPushed, true);
            app.SaveWaveform.Text = 'Save Waveform';
            app.ReadyStateLbl = uilabel(app.Waveforms);
            app.ReadyStateLbl.Text = 'Ready...';
            app.LabelNumericEditField2 = uilabel(app.Waveforms);
            app.LabelNumericEditField2.Text = 'Save Scale Factor';
            app.SaveScaleFactor = uieditfield(app.Waveforms, 'numeric');
            app.SaveScaleFactor.Limits = [10000 100000000000000];
            app.SaveScaleFactor.Value = 10000000;
            app.SIRLbl = uilabel(app.Waveforms);
            app.SIRLbl.Text = 'SIR';
            app.ShowSIR = uicheckbox(app.Waveforms);
            app.ShowSIR.ValueChangedFcn = createCallbackFcn(app, @ShowSIRValueChanged, true);
            app.ShowSIR.Text = 'Show SIR';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Signal Plots';
            app.Tab.Units = 'pixels';
            app.StatPlot = uitabgroup(app.Tab);
            app.RunningAverage = uitab(app.StatPlot);
            app.RunningAverage.Title = 'Running Averages';
            app.RunningAverage.Units = 'pixels';
            app.SelectRunningAverageFolder = uibutton(app.RunningAverage, 'push');
            app.SelectRunningAverageFolder.ButtonPushedFcn = createCallbackFcn(app, @SelectRunningAverageFolderButtonPushed, true);
            app.SelectRunningAverageFolder.Text = 'Select Running Average Folder';
            app.RunAveLbl = uilabel(app.RunningAverage);
            app.RunAveLbl.Text = 'Dir:';
            app.UIAxes = uiaxes(app.RunningAverage);
            title(app.UIAxes, 'Running Average');
            xlabel(app.UIAxes, 'Time sec');
            ylabel(app.UIAxes, 'Power dB');
            app.ClearPlot = uibutton(app.RunningAverage, 'push');
            app.ClearPlot.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlot.Text = 'Clear Plot';
            app.Label31 = uilabel(app.RunningAverage);
            app.Label31.Text = 'Select File';
            app.SelectRunninAverageFile = uilistbox(app.RunningAverage);
            app.SelectRunninAverageFile.Items = {};
            app.SelectRunninAverageFile.ValueChangedFcn = createCallbackFcn(app, @SelectRunninAverageFileValueChanged, true);
            app.SelectRunninAverageFile.Value = {};
            app.DebugLbl = uilabel(app.ESCWaveGen);
            app.DebugLbl.Text = 'Label_DEBUG';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ESCWaveformGenerator()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ESCWaveGen)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ESCWaveGen)
        end
    end
end